classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ColorCorrectionAppLabel   matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        InterpolationMapTab       matlab.ui.container.Tab
        ConvertToRgbButton        matlab.ui.control.Button
        InterpolationMapPanel     matlab.ui.container.Panel
        ClearMapButton            matlab.ui.control.Button
        InterpolationTable        matlab.ui.control.Table
        SelectPixelPanel          matlab.ui.container.Panel
        ClearButton               matlab.ui.control.Button
        AddToMapButton            matlab.ui.control.Button
        RedValueLabel             matlab.ui.control.Label
        SelectedRgbLabel          matlab.ui.control.Label
        SelectRgbButton           matlab.ui.control.Button
        SavedScalarValueLabel     matlab.ui.control.Label
        SavedScalarLabel          matlab.ui.control.Label
        CurrentScalarValueLabel   matlab.ui.control.Label
        CurrentScalarLabel        matlab.ui.control.Label
        ImagePlaceholderAxes      matlab.ui.control.UIAxes
        StatusLabel               matlab.ui.control.Label
        UploadMonoImageButton     matlab.ui.control.Button
        ColorCorrectionMatrixTab  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        img % Uploaded image.
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadMonoImageButton
        function onUploadMonoImageButtonPushed(app, event)
            % Open a file selection dialog for image files.
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'});
            
            % Check if the user selected a file.
            if isequal(file, 0)
                return; % User canceled, do nothing.
            end
            
            % Construct the full file path.
            filePath = fullfile(path, file);
            
            % Load the image.
            app.img = imread(filePath);

            % Validate image is monochrome.
            % If valid:
                % Display image
                % Change status to '<filename>'
            % else:
                % do uialert
                % change status label to 'try again'
            
            % Display the image in the panel, and update text on status
            % label and button.
            imshow(app.img, 'Parent', app.ImagePlaceholderAxes);
            % app.Image.ImageSource = filePath;
            app.UploadMonoImageButton.Text = 'Change image';
            app.StatusLabel.Text = ['Uploaded ', file];
        end

        % Window button motion function: UIFigure
        function onCursorOverImageMotion(app, event)
            if isempty(app.img)  % No image has been uploaded, do nothing.
                return
            end
            
            % Get the current mouse position in the figure.
            currPoint = app.UIFigure.CurrentPoint; % [x, y] coordinates in figure.

            % Get positions of relevant components.
            panelPos = app.SelectPixelPanel.Position;
            axPos = app.ImagePlaceholderAxes.Position; % Axes position relative to its parent panel.
            
            % Get the axes position relative to the figure.
            axPos(1) = axPos(1) + panelPos(1); 
            axPos(2) = axPos(2) + panelPos(2); 
            
            % Calculate mouse position relative to the axes.
            xNorm = (currPoint(1) - axPos(1)) / axPos(3); % Normalized x position (0 to 1).
            yNorm = (currPoint(2) - axPos(2)) / axPos(4); % Normalized y position (0 to 1).
            
            % Check if the normalized position is within the axes bounds.
            if xNorm >= 0 && xNorm <= 1 && yNorm >= 0 && yNorm <= 1
                % Convert normalized coordinates to pixel indices in the image.
                x = round(xNorm * size(app.img, 2)); % x-coordinate in image.
                y = round((1 - yNorm) * size(app.img, 1)); % y-coordinate in image (invert y).
            
                % Ensure indices are within bounds of the image.
                if x >= 1 && x <= size(app.img, 2) && y >= 1 && y <= size(app.img, 1)
                    % Get the grayscale value at the current position.
                    grayValue = app.img(y, x);
            
                    % Update the label with the grayscale value.
                    app.CurrentScalarValueLabel.Text = sprintf('%d', grayValue);
                else
                    % Outside image bounds.
                    app.CurrentScalarValueLabel.Text = 'N/A';
                end
            else
                % Mouse is outside the axes.
                app.CurrentScalarValueLabel.Text = 'N/A';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @onCursorOverImageMotion, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterpolationMapTab = uitab(app.TabGroup);
            app.InterpolationMapTab.Title = 'Interpolation Map';
            app.UploadMonoImageButton = uibutton(app.InterpolationMapTab, 'push');
            app.UploadMonoImageButton.ButtonPushedFcn = createCallbackFcn(app, @onUploadMonoImageButtonPushed, true);
            app.UploadMonoImageButton.Text = 'Upload monochrome image';
            app.StatusLabel = uilabel(app.InterpolationMapTab);
            app.StatusLabel.Text = 'No file selected';
            app.SelectPixelPanel = uipanel(app.InterpolationMapTab);
            app.SelectPixelPanel.Title = 'Select Pixel Panel';
            app.ImagePlaceholderAxes = uiaxes(app.SelectPixelPanel);
            app.ImagePlaceholderAxes.XTick = [];
            app.ImagePlaceholderAxes.XTickLabel = '';
            app.ImagePlaceholderAxes.YTick = [];
            app.CurrentScalarLabel = uilabel(app.SelectPixelPanel);
            app.CurrentScalarLabel.Text = 'Current scalar:';
            app.CurrentScalarValueLabel = uilabel(app.SelectPixelPanel);
            app.CurrentScalarValueLabel.Text = '<###>';
            app.SavedScalarLabel = uilabel(app.SelectPixelPanel);
            app.SavedScalarLabel.Text = 'Saved scalar:';
            app.SavedScalarValueLabel = uilabel(app.SelectPixelPanel);
            app.SavedScalarValueLabel.Text = '<###>';
            app.SelectRgbButton = uibutton(app.SelectPixelPanel, 'push');
            app.SelectRgbButton.Text = 'Select RGB';
            app.SelectedRgbLabel = uilabel(app.SelectPixelPanel);
            app.SelectedRgbLabel.Text = 'RGB values:';
            app.RedValueLabel = uilabel(app.SelectPixelPanel);
            app.RedValueLabel.Text = '<###>';
            app.AddToMapButton = uibutton(app.SelectPixelPanel, 'push');
            app.AddToMapButton.Text = 'Add to map';
            app.ClearButton = uibutton(app.SelectPixelPanel, 'push');
            app.ClearButton.Text = 'Clear';
            app.InterpolationMapPanel = uipanel(app.InterpolationMapTab);
            app.InterpolationMapPanel.Title = 'Interpolation Map';
            app.InterpolationTable = uitable(app.InterpolationMapPanel);
            app.InterpolationTable.ColumnName = {'Scalar'; 'R'; 'G'; 'B'};
            app.InterpolationTable.RowName = {};
            app.ClearMapButton = uibutton(app.InterpolationMapPanel, 'push');
            app.ClearMapButton.Text = 'Clear map';
            app.ConvertToRgbButton = uibutton(app.InterpolationMapTab, 'push');
            app.ConvertToRgbButton.Text = 'Convert to RGB';
            app.ColorCorrectionMatrixTab = uitab(app.TabGroup);
            app.ColorCorrectionMatrixTab.Title = 'Color Correction Matrix';
            app.ColorCorrectionAppLabel = uilabel(app.UIFigure);
            app.ColorCorrectionAppLabel.Text = 'Color Correction App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end